classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ResettoHomePositionButton   matlab.ui.control.Button
        RunInverseKinematicsButton  matlab.ui.control.Button
        YawEditField                matlab.ui.control.NumericEditField
        YawEditFieldLabel           matlab.ui.control.Label
        PitchEditField              matlab.ui.control.NumericEditField
        PitchEditFieldLabel         matlab.ui.control.Label
        RollEditField               matlab.ui.control.NumericEditField
        RollEditFieldLabel          matlab.ui.control.Label
        ZEditField                  matlab.ui.control.NumericEditField
        ZEditFieldLabel             matlab.ui.control.Label
        YEditField                  matlab.ui.control.NumericEditField
        YEditFieldLabel             matlab.ui.control.Label
        XEditField                  matlab.ui.control.NumericEditField
        XEditFieldLabel             matlab.ui.control.Label
        Theta6EditField             matlab.ui.control.NumericEditField
        Theta6EditFieldLabel        matlab.ui.control.Label
        Theta5EditField             matlab.ui.control.NumericEditField
        Theta5EditFieldLabel        matlab.ui.control.Label
        Theta4EditField             matlab.ui.control.NumericEditField
        Theta4EditFieldLabel        matlab.ui.control.Label
        Theta3EditField             matlab.ui.control.NumericEditField
        Theta3EditFieldLabel        matlab.ui.control.Label
        Theta2EditField             matlab.ui.control.NumericEditField
        Theta2EditFieldLabel        matlab.ui.control.Label
        Theta1EditField             matlab.ui.control.NumericEditField
        Theta1EditFieldLabel        matlab.ui.control.Label
        RunForwardKinematicsButton  matlab.ui.control.Button
        RobotAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        V0_orig
        F
        C
        numLinks
        LinkPatches
        theta_current
        theta_old
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load robot data
            [V0, F, C, numLinks] = loadRobotData('RobotName_LinksData.mat');
            
            % Store originals as app properties so callbacks can access them later
            app.V0_orig = V0;
            app.F = F;
            app.C = C;
            app.numLinks = numLinks;
            
            % Draw robot into YOUR app's axes (not a new figure)
            app.LinkPatches = drawRobot(app.RobotAxes, app.V0_orig, app.F, app.numLinks);
            
            % Start at zero position
            app.theta_current = [0, 0, 0, 0, 0, 0];
            app.theta_old = app.theta_current;
            updateRobot(app.LinkPatches, app.V0_orig, app.theta_current);

            theta = [app.Theta1EditField.Value
             app.Theta2EditField.Value
             app.Theta3EditField.Value
             app.Theta4EditField.Value
             app.Theta5EditField.Value
             app.Theta6EditField.Value]';
            
               
            updateRobot(app.LinkPatches, app.V0_orig, theta)
            [pos, ~, euler] = getEEPose(theta);
            
            app.XEditField.Value  = pos(1);
            app.YEditField.Value  = pos(2);
            app.ZEditField.Value  = pos(3);
            app.RollEditField.Value = euler(1);
            app.PitchEditField.Value = euler(2);
            app.YawEditField.Value = euler(3);
            
        end

        % Button pushed function: RunForwardKinematicsButton
        function RunForwardKinematicsButtonPushed(app, event)
           
            theta = [app.Theta1EditField.Value
             app.Theta2EditField.Value
             app.Theta3EditField.Value
             app.Theta4EditField.Value
             app.Theta5EditField.Value
             app.Theta6EditField.Value]';
            
               
            animateFK(app.LinkPatches, app.V0_orig, app.theta_old, theta)
            [pos, ~, euler] = getEEPose(theta);
            app.theta_old = theta;
            
            app.XEditField.Value  = pos(1);
            app.YEditField.Value  = pos(2);
            app.ZEditField.Value  = pos(3);
            app.RollEditField.Value = euler(1);
            app.PitchEditField.Value = euler(2);
            app.YawEditField.Value = euler(3);
            
        end

        % Button pushed function: RunInverseKinematicsButton
        function RunInverseKinematicsButtonPushed(app, event)
            target = [
                app.XEditField.Value
                app.YEditField.Value
                app.ZEditField.Value
            ];
            rpy = deg2rad([
                app.RollEditField.Value
                app.PitchEditField.Value
                app.YawEditField.Value
            ]);
            theta_init = [0 0 0 0 0 0];
            
            
            theta_out = inverseKinematics(target,rpy,theta_init);
        
            app.Theta1EditField.Value = (theta_out(1));
            app.Theta2EditField.Value = (theta_out(2));
            app.Theta3EditField.Value = (theta_out(3));
            app.Theta4EditField.Value = (theta_out(4));
            app.Theta5EditField.Value = (theta_out(5));
            app.Theta6EditField.Value = (theta_out(6));

            animateFK(app.LinkPatches, app.V0_orig,app.theta_old, theta_out);
            app.theta_old = theta_out;
        end

        % Button pushed function: ResettoHomePositionButton
        function ResettoHomePositionButtonPushed(app, event)
                % Reset all joint angle inputs to zero
                app.Theta1EditField.Value = 0;
                app.Theta2EditField.Value = 0;
                app.Theta3EditField.Value = 0;
                app.Theta4EditField.Value = 0;
                app.Theta5EditField.Value = 0;
                app.Theta6EditField.Value = 0;
            
                % Reset all EE output fields to zero
                app.XEditField.Value     = 0;
                app.YEditField.Value     = 0;
                app.ZEditField.Value     = 0;
                app.RollEditField.Value  = 0;
                app.PitchEditField.Value = 0;
                app.YawEditField.Value   = 0;

                % Reset robot visual back to home position
                app.theta_current = [0, 0, 0, 0, 0, 0];
                animateFK(app.LinkPatches, app.V0_orig,app.theta_old, app.theta_current);

                theta = [app.Theta1EditField.Value
                 app.Theta2EditField.Value
                 app.Theta3EditField.Value
                 app.Theta4EditField.Value
                 app.Theta5EditField.Value
                 app.Theta6EditField.Value]';
                
                [pos, ~, euler] = getEEPose(theta);
                app.theta_old = theta;
                
                app.XEditField.Value  = pos(1);
                app.YEditField.Value  = pos(2);
                app.ZEditField.Value  = pos(3);
                app.RollEditField.Value = euler(1);
                app.PitchEditField.Value = euler(2);
                app.YawEditField.Value = euler(3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RobotAxes = uiaxes(app.UIFigure);
            title(app.RobotAxes, 'Title')
            xlabel(app.RobotAxes, 'X')
            ylabel(app.RobotAxes, 'Y')
            zlabel(app.RobotAxes, 'Z')
            app.RunForwardKinematicsButton = uibutton(app.UIFigure, 'push');
            app.RunForwardKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @RunForwardKinematicsButtonPushed, true);
            app.RunForwardKinematicsButton.Text = 'Run Forward Kinematics';
            app.Theta1EditFieldLabel = uilabel(app.UIFigure);
            app.Theta1EditFieldLabel.Text = 'Theta 1';
            app.Theta1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta1EditField.Limits = [-360 360];
            app.Theta2EditFieldLabel = uilabel(app.UIFigure);
            app.Theta2EditFieldLabel.Text = 'Theta 2';
            app.Theta2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta2EditField.Limits = [-360 360];
            app.Theta3EditFieldLabel = uilabel(app.UIFigure);
            app.Theta3EditFieldLabel.Text = 'Theta 3';
            app.Theta3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta3EditField.Limits = [-360 360];
            app.Theta4EditFieldLabel = uilabel(app.UIFigure);
            app.Theta4EditFieldLabel.Text = 'Theta 4';
            app.Theta4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta4EditField.Limits = [-360 360];
            app.Theta5EditFieldLabel = uilabel(app.UIFigure);
            app.Theta5EditFieldLabel.Text = 'Theta 5';
            app.Theta5EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta5EditField.Limits = [-360 360];
            app.Theta6EditFieldLabel = uilabel(app.UIFigure);
            app.Theta6EditFieldLabel.Text = 'Theta 6';
            app.Theta6EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta6EditField.Limits = [-360 360];
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.Limits = [-360 360];
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.Limits = [-360 360];
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.Limits = [-360 360];
            app.RollEditFieldLabel = uilabel(app.UIFigure);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.UIFigure, 'numeric');
            app.RollEditField.Limits = [-360 360];
            app.PitchEditFieldLabel = uilabel(app.UIFigure);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchEditField.Limits = [-360 360];
            app.YawEditFieldLabel = uilabel(app.UIFigure);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.UIFigure, 'numeric');
            app.YawEditField.Limits = [-360 360];
            app.RunInverseKinematicsButton = uibutton(app.UIFigure, 'push');
            app.RunInverseKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @RunInverseKinematicsButtonPushed, true);
            app.RunInverseKinematicsButton.Text = 'Run Inverse Kinematics';
            app.ResettoHomePositionButton = uibutton(app.UIFigure, 'push');
            app.ResettoHomePositionButton.ButtonPushedFcn = createCallbackFcn(app, @ResettoHomePositionButtonPushed, true);
            app.ResettoHomePositionButton.Text = 'Reset to Home Position';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end